classdef Dynamic_Scaling_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        lander_height %
        lander_width %
        vehicle
        scale
        Sarm
        Sarm_x_position
        Sarm_y_position
        Sarm_x_position2
        Sarm_y_position2
        Sarm_x_position3
        Sarm_y_position3
        Sarm_length
        Sarm_width
        Sarm_base
        Sarm_height
        Sarm_secondlink
        Sarm_endeffector
        Sarm_angle
        Sarm_angle_L2
        Sarm_angle_L3
        vehicle_body
        lander_body
        lander_legL
        lander_legR
        lander_legM
        base
        link3
        link2
        arm
        arm_length
        arm_width
        arm_base
        arm_secondlink
        arm_endeffector
        arm_mass1
        arm_mass2
        arm_mass3
        arm_weight1
        arm_weight2
        arm_weight3
        arm_x_position
        arm_y_position
        arm_x_position2
        arm_y_position2
        arm_x_position3
        arm_y_position3
        arm_angle
        arm_angle_L2
        arm_angle_L3
        scale_factor
        rotationS
        rotationB
        rotationEE
        hgTx
        hgTxB
        hgTxB_Matrix
        hgTxS
        hgTxS_Matrix
        hgTxE
        hgTxE_Matrix
        vehicle_length
        vehicle_height
        vehicle_wheelRadius
    end
    
    methods (Access = private)
        
        function scaling(app)
            %lander parameters 
            app.lander_height = 3*app.scale_factor;
            app.lander_width = 2*app.scale_factor;
            
            % Vehicle Structure 
            app.vehicle_length = 1.75*app.scale_factor;
            app.vehicle_wheelRadius = 0.4*app.scale_factor;
            app.vehicle_height = (1.2*app.scale_factor)-app.vehicle_wheelRadius;
            
            % Creates the structure for the robotic arm parameter
            app.Sarm_length = app.arm_length*app.scale_factor;
            app.Sarm_width = app.arm_width*app.scale_factor;
            app.Sarm_base = app.arm_base*app.scale_factor;
            app.Sarm_secondlink = app.arm_secondlink*app.scale_factor;
            app.Sarm_endeffector = app.arm_endeffector*app.scale_factor;
            % Sarm_gripper = arm_gripper*scale_factor;
            
            % Base linkage of the arm position and orientation
            app.Sarm_x_position = 1.75*app.scale_factor;
            app.Sarm_y_position = (app.vehicle_height+app.vehicle_wheelRadius); 
            app.Sarm_angle = 25*pi/180;
            
            % Second linkage of the arm position and orientation
            app.Sarm_angle_L2 = 0*pi/180;
            app.Sarm_x_position2 = app.Sarm_x_position+app.Sarm_base*cos(app.Sarm_angle);
            app.Sarm_y_position2 = app.Sarm_y_position+app.Sarm_base*sin(app.Sarm_angle);
            
            % End Effector linkage of the arm position and orientation
            app.Sarm_angle_L3 = 200*pi/180;
            app.Sarm_x_position3 = app.Sarm_x_position2+app.Sarm_secondlink*cos(app.Sarm_angle_L2);
            app.Sarm_y_position3 = app.Sarm_y_position2+app.Sarm_secondlink*sin(app.Sarm_angle_L2);
            
            % % Simplified Model of Gripper
            % Sarm_angle_L4 = 270*pi/180;
            % Sarm_x_position4 = Sarm_x_position3+Sarm_endeffector*sin(Sarm_angle_L3);
            % Sarm_y_position4 = Sarm_y_position3+Sarm_endeffector*cos(Sarm_angle_L3)
            
            % Sets figure settings
            fig = figure();
            ax = axes('XLim',[-1 10],'YLim',[-1 10]);
            
            % Parent handler of the transform
            app.hgTx = hgtransform('Parent',ax);
            app.hgTxB = hgtransform('Parent',ax);
            app.hgTxS = hgtransform('Parent',ax);
            app.hgTxE = hgtransform('Parent',ax);
            % hgTxG = hgtransform('Parent',ax);
            
            % rectangle is in the form [x y w h] 
            
            % Creates the vehicle body object 
            app.vehicle_body = rectangle('Parent', app.hgTx, 'Position',[(app.lander_width/2)+(0.25*app.scale_factor) app.vehicle_wheelRadius app.vehicle_length app.vehicle_height], 'FaceColor','red');
            
            % Creates the lander body object 
            app.lander_body = rectangle('Parent', app.hgTx, 'Position',[-app.lander_width/2 app.scale_factor app.lander_width app.lander_height-(1*app.scale_factor)], 'EdgeColor','black');
            app.lander_legL = rectangle('Parent', app.hgTx, 'Position',[-app.lander_width/2 0 app.lander_width*0.1 app.scale_factor], 'FaceColor','black');
            app.lander_legR = rectangle('Parent', app.hgTx, 'Position',[(app.lander_width/2)-app.lander_width*0.1 0 app.lander_width*0.1 app.scale_factor], 'FaceColor','black');
            app.lander_legM = rectangle('Parent', app.hgTx, 'Position',[0-((app.lander_width*0.1)/2) 0 app.lander_width*0.1 app.scale_factor], 'FaceColor','black');
            
            % Creates objects to simulate the arm
            app.base = rectangle('Parent', app.hgTxB, 'Position',[0 0 app.Sarm_base app.Sarm_width], 'EdgeColor','red');
            app.link2 = rectangle('Parent', app.hgTxS, 'Position',[0 0 app.Sarm_secondlink app.Sarm_width], 'EdgeColor','red');
            app.link3 = rectangle('Parent', app.hgTxE, 'Position',[0 0 app.Sarm_endeffector app.Sarm_width], 'EdgeColor','red');
            % gripper = rectangle('Parent', hgTxG, 'Position',[0 0 Sarm_gripper Sarm_width], 'FaceColor','yellow');
            % Rotation of Robot Linkages
            app.rotationB = linspace(108,110,20)*(pi/180);
            app.rotationS = linspace(34,90,10)*(pi/180);
            app.rotationEE = linspace(-45,180,10)*(pi/180);
            
            y = -1:0.01:10; % for the dashed line indicating center of the lander 
            x = 0*ones(1,length(y)); % enough zeros for vectors to be equal 
            
            x2 = -1:0.01:10; % for the dashed line indicating center of the lander 
            y2 = 1.5*ones(1,length(y)); % enough zeros for vectors to be equal 
            
            % Text strings to show the lengths on the plot 
            base_length = ['Length of Base: ',num2str(app.Sarm_base), 'm'];
            second_length = ['Length of Link2: ',num2str(app.Sarm_secondlink), 'm'];
            endeff_length = ['Length of End Effector: ',num2str(app.Sarm_endeffector), 'm'];
            title_str = ['Robotic Arm Simulation at Scale ',num2str(round(app.scale_factor,2))];
            % gripper_length = ['Length of Gripper: ',num2str(arm_gripper), 'm'];
            
            % v = VideoWriter('scaled1.mp4'); % create video object
            % v.Quality = 50; % sets the video quality 
            % open(v); % open the video object 
            pause(5)
            for i = 1:length(app.rotationS)
                app.Sarm_angle = app.rotationB(i); 
                app.Sarm_angle_L2 =  app.rotationS(i); % rotating the secondary linkage
                app.Sarm_angle_L3 = app.rotationEE(i); % rotating the end effector 
                
                app.Sarm_x_position2 = app.Sarm_x_position+app.Sarm_base*cos(app.Sarm_angle); % secondary linkage position updates based on base link
                app.Sarm_y_position2 = app.Sarm_y_position+app.Sarm_base*sin(app.Sarm_angle);
            
                app.Sarm_x_position3 = app.Sarm_x_position2+app.Sarm_secondlink*cos(app.Sarm_angle_L2); % EE updates position based on secondary linkage 
                app.Sarm_y_position3 = app.Sarm_y_position2+app.Sarm_secondlink*sin(app.Sarm_angle_L2);
                
            %     Sarm_x_position4 = Sarm_x_position3+Sarm_endeffector*cos(Sarm_angle_L3);
            %     Sarm_y_position4 = Sarm_y_position3+Sarm_endeffector*sin(Sarm_angle_L3);
            
                app.hgTxB.Matrix = makehgtform('translate', [app.Sarm_x_position app.Sarm_y_position 0], 'zrotate', app.Sarm_angle);
                app.hgTxS.Matrix = makehgtform('translate', [app.Sarm_x_position2 app.Sarm_y_position2 0], 'zrotate', app.Sarm_angle_L2);
                app.hgTxE.Matrix = makehgtform('translate', [app.Sarm_x_position3 app.Sarm_y_position3 0], 'zrotate', app.Sarm_angle_L3);
                
                % makes the plots pretty 
                hold on 
                xlabel(ax,'X (m)')
                ylabel(ax,'Y (m)')
                title(ax,title_str,'FontSize',15)
                text(7,7.5,base_length); 
                text(7,7,second_length);
                text(7,6.5,endeff_length); 
                plot(ax,x,y,'k--')
                plot(ax,x2,y2,'k--')
                grid minor 
                hold off 
                % the pause is so the loop doesn't fly through the rotation array 
                pause(.75)
            end            
            % Rotation of Robot Linkages
            app.rotationB = linspace(110,108,20)*(pi/180);
            app.rotationS = linspace(90,34,10)*(pi/180);
            app.rotationEE = linspace(180,-45,10)*(pi/180);
            
            y = -1:0.01:10; % for the dashed line indicating center of the lander 
            x = 0*ones(1,length(y)); % enough zeros for vectors to be equal 
            
            x2 = -1:0.01:10; % for the dashed line indicating center of the lander 
            y2 = 1.5*ones(1,length(y)); % enough zeros for vectors to be equal 
            
            % Text strings to show the lengths on the plot 
            base_length = ['Length of Base: ',num2str(app.Sarm_base), 'm'];
            second_length = ['Length of Link2: ',num2str(app.Sarm_secondlink), 'm'];
            endeff_length = ['Length of End Effector: ',num2str(app.Sarm_endeffector), 'm'];
            title_str = ['Robotic Arm Simulation at Scale ',num2str(round(app.scale_factor,2))];
            % gripper_length = ['Length of Gripper: ',num2str(arm_gripper), 'm'];
            
            % v = VideoWriter('scaled1.mp4'); % create video object
            % v.Quality = 50; % sets the video quality 
            % open(v); % open the video object 
        
            for i = 1:length(app.rotationS)
                app.Sarm_angle = app.rotationB(i); 
                app.Sarm_angle_L2 =  app.rotationS(i); % rotating the secondary linkage
                app.Sarm_angle_L3 = app.rotationEE(i); % rotating the end effector 
                
                app.Sarm_x_position2 = app.Sarm_x_position+app.Sarm_base*cos(app.Sarm_angle); % secondary linkage position updates based on base link
                app.Sarm_y_position2 = app.Sarm_y_position+app.Sarm_base*sin(app.Sarm_angle);
            
                app.Sarm_x_position3 = app.Sarm_x_position2+app.Sarm_secondlink*cos(app.Sarm_angle_L2); % EE updates position based on secondary linkage 
                app.Sarm_y_position3 = app.Sarm_y_position2+app.Sarm_secondlink*sin(app.Sarm_angle_L2);
                
            %     Sarm_x_position4 = Sarm_x_position3+Sarm_endeffector*cos(Sarm_angle_L3);
            %     Sarm_y_position4 = Sarm_y_position3+Sarm_endeffector*sin(Sarm_angle_L3);
            
                app.hgTxB.Matrix = makehgtform('translate', [app.Sarm_x_position app.Sarm_y_position 0], 'zrotate', app.Sarm_angle);
                app.hgTxS.Matrix = makehgtform('translate', [app.Sarm_x_position2 app.Sarm_y_position2 0], 'zrotate', app.Sarm_angle_L2);
                app.hgTxE.Matrix = makehgtform('translate', [app.Sarm_x_position3 app.Sarm_y_position3 0], 'zrotate', app.Sarm_angle_L3);
                
                % makes the plots pretty 
                hold on 
                xlabel(ax,'X (m)')
                ylabel(ax,'Y (m)')
                title(ax,title_str,'FontSize',15)
                text(7,7.5,base_length); 
                text(7,7,second_length);
                text(7,6.5,endeff_length); 
                plot(ax,x,y,'k--')
                plot(ax,x2,y2,'k--')
                grid minor 
                hold off 
                % the pause is so the loop doesn't fly through the rotation array 
                pause(.75)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %lander parameters 
            app.lander_height = 3; 
            app.lander_width = 2; 
            
            app.scale_factor = 1;
            % Vehicle Structure 
            app.vehicle_length = 1.75;
            app.vehicle_wheelRadius = 0.4;
            app.vehicle_height = 1.2-app.vehicle_wheelRadius;
            
            % Creates the structure for the robotic arm parameter
            app.arm_length = (app.lander_height+0.5);
            app.arm_width = 0.25;
            app.arm_base = app.arm_length*0.35;
            app.arm_secondlink = app.arm_length*0.35;
            app.arm_endeffector = app.arm_length*0.2;
            % arm_gripper = arm_length*0.4;
            
            % Volumes and Masses 
            % density of titanium is 4.056 g/cm^3
            % density of graphite epoxy is 2.26 g/cm^3
            p = 1000; %4.056*(1/1000)*(100^3); % density 
            g = 9.81; % m/s^2, lunar gravity 
            % Volume*p = mass 
            app.arm_mass1 = app.arm_base*pi*((app.arm_width/4)^2)*p;
            app.arm_mass2 = app.arm_secondlink*pi*((app.arm_width/4)^2)*p;
            app.arm_mass3 = app.arm_endeffector*pi*((app.arm_width/4)^2)*p;
            
            % scaled mass from LeWan Soul =    2.1484e+03 kg 
            % Mass * gravity = weight; 
            app.arm_weight1 = app.arm_mass1*g;
            app.arm_weight2 = app.arm_mass2*g;
            app.arm_weight3 = app.arm_mass3*g;
            
            % arm_weight1 =  ((2.1484e+03)*0.2)*g
            % % (((2.1484e+03)-((2.1484e+03)*0.2))/2)
            % arm_weight2 = (((2.1484e+03)-((2.1484e+03)*0.2))/2)*g
            % arm_weight3 = (((2.1484e+03)-((2.1484e+03)*0.2))/2)*g
            
            
            % Base linkage of the arm position and orientation
            app.arm_x_position = 1.75;
            app.arm_y_position = app.vehicle_height+app.vehicle_wheelRadius; 
            app.arm_angle = 128*pi/180;
            
            % Second linkage of the arm position and orientation
            app.arm_angle_L2 = 0*pi/180;
            app.arm_x_position2 = app.arm_x_position+app.arm_base*cos(app.arm_angle);
            app.arm_y_position2 = app.arm_y_position+app.arm_base*sin(app.arm_angle);
            
            % End Effector linkage of the arm position and orientation
            app.arm_angle_L3 = 200*pi/180;
            app.arm_x_position3 = app.arm_x_position2+app.arm_secondlink*cos(app.arm_angle_L2);
            app.arm_y_position3 = app.arm_y_position2+app.arm_secondlink*sin(app.arm_angle_L2);
            
            % % Simplified Model of Gripper
            % arm_angle_L4 = 270*pi/180;
            % arm_x_position4 = arm_x_position3+arm_endeffector*sin(arm_angle_L3);
            % arm_y_position4 = arm_y_position3+arm_endeffector*cos(arm_angle_L3)
            
            % Prototype Arm 
            LeWanxarm_base = 101.73/1000; % m 
            LeWanxarm_secondlink = 101.73/1000; % m 
            LeWanxarm_EE = 56.642/1000; % m 
            % LeWanxarm_G = 114.808/1000; % m 
            
            app.scale(1) = LeWanxarm_base/app.arm_base;
            app.scale(2)=LeWanxarm_secondlink/app.arm_secondlink;
            app.scale(3) = LeWanxarm_EE/app.arm_endeffector;
            % scale(4) = LeWanxarm_G/arm_gripper;
            app.scale_factor = 0.5;
            scaling(app);
            clf;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.scale_factor = value;
            scaling(app);
            %imshow('Parent', app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dynamic_Scaling_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end